classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        MobileAppVisualizerPanel  matlab.ui.container.Panel
        FrecuenciaButtonGroup     matlab.ui.container.ButtonGroup
        Button3_2                 matlab.ui.control.RadioButton
        Button2_2                 matlab.ui.control.RadioButton
        Button_2                  matlab.ui.control.RadioButton
        SOStoreButtonGroup        matlab.ui.container.ButtonGroup
        Button3                   matlab.ui.control.RadioButton
        Button2                   matlab.ui.control.RadioButton
        Button                    matlab.ui.control.RadioButton
        PeriodoSlider             matlab.ui.control.Slider
        PeriodoSliderLabel        matlab.ui.control.Label
        PaisListBox               matlab.ui.control.ListBox
        PasListBoxLabel           matlab.ui.control.Label
        ContinenteListBox         matlab.ui.control.ListBox
        ContinenteListBoxLabel    matlab.ui.control.Label
        PresentarDatosButton      matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        isDataLoaded logical = 0;
        objDataset; % Dataset object
        datatable; % A variable to access data because it's no possible to do so with function getData()
        countries;
    end
    
    methods (Access = private)
        
        function cargarDatos(app)
            if app.isDataLoaded == 0
                app.objDataset = MobileAppDataset('count_per_month.csv');
                app.datatable = app.objDataset.getData();
                app.countries = app.objDataset.getCountries();
                app.PaisListBox.Items = table2array(app.countries(:,3));
                app.isDataLoaded = 1;
            end
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.cargarDatos();
            disp(horzcat('DataLoaded: ',num2str(app.isDataLoaded)))
        end

        % Button pushed function: PresentarDatosButton
        function datosButtonPushed(app, event)
            [releaseDate, cant] = app.objDataset.getCountPerMonth();
            plot(app.UIAxes,releaseDate, cant)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.MobileAppVisualizerPanel = uipanel(app.UIFigure);
            app.MobileAppVisualizerPanel.Title = 'MobileAppVisualizer';
            app.UIAxes = uiaxes(app.MobileAppVisualizerPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.PresentarDatosButton = uibutton(app.MobileAppVisualizerPanel, 'push');
            app.PresentarDatosButton.ButtonPushedFcn = createCallbackFcn(app, @datosButtonPushed, true);
            app.PresentarDatosButton.Text = 'Presentar';
            app.ContinenteListBoxLabel = uilabel(app.MobileAppVisualizerPanel);
            app.ContinenteListBoxLabel.Text = 'Continente';
            app.ContinenteListBox = uilistbox(app.MobileAppVisualizerPanel);
            app.PasListBoxLabel = uilabel(app.MobileAppVisualizerPanel);
            app.PasListBoxLabel.Text = 'País';
            app.PaisListBox = uilistbox(app.MobileAppVisualizerPanel);
            app.PeriodoSliderLabel = uilabel(app.MobileAppVisualizerPanel);
            app.PeriodoSliderLabel.Text = 'Periodo';
            app.PeriodoSlider = uislider(app.MobileAppVisualizerPanel);
            app.SOStoreButtonGroup = uibuttongroup(app.MobileAppVisualizerPanel);
            app.SOStoreButtonGroup.Title = 'SO - Store';
            app.Button = uiradiobutton(app.SOStoreButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.SOStoreButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.SOStoreButtonGroup);
            app.Button3.Text = 'Button3';
            app.FrecuenciaButtonGroup = uibuttongroup(app.MobileAppVisualizerPanel);
            app.FrecuenciaButtonGroup.Title = 'Frecuencia';
            app.Button_2 = uiradiobutton(app.FrecuenciaButtonGroup);
            app.Button_2.Text = 'Button';
            app.Button_2.Value = true;
            app.Button2_2 = uiradiobutton(app.FrecuenciaButtonGroup);
            app.Button2_2.Text = 'Button2';
            app.Button3_2 = uiradiobutton(app.FrecuenciaButtonGroup);
            app.Button3_2.Text = 'Button3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end